classdef samplingBenchmarkInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SamplingTypeDropDownLabel     matlab.ui.control.Label
        SamplingTypeDropDown          matlab.ui.control.DropDown
        UniformityCheckBox            matlab.ui.control.CheckBox
        MintargetCheckBox             matlab.ui.control.CheckBox
        MinhealthyCheckBox            matlab.ui.control.CheckBox
        FMOObjectivesLabel            matlab.ui.control.Label
        UniformityCheckBox_2          matlab.ui.control.CheckBox
        UpperboundsCheckBox           matlab.ui.control.CheckBox
        CVaRCheckBox                  matlab.ui.control.CheckBox
        BasicdoseCheckBox             matlab.ui.control.CheckBox
        FMOConstraintsLabel           matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        layersEditFieldLabel          matlab.ui.control.Label
        layersEditField               matlab.ui.control.EditField
        voxelsEditFieldLabel          matlab.ui.control.Label
        voxelsEditField               matlab.ui.control.EditField
        OutputTextAreaLabel           matlab.ui.control.Label
        OutputTextArea                matlab.ui.control.TextArea
        ChoosefileButton              matlab.ui.control.Button
        FileselectedTextAreaLabel     matlab.ui.control.Label
        FileselectedTextArea          matlab.ui.control.TextArea
        SampleintervalEditFieldLabel  matlab.ui.control.Label
        SampleintervalEditField       matlab.ui.control.EditField
        ofclustersEditFieldLabel      matlab.ui.control.Label
        numofclustersEditField        matlab.ui.control.EditField
        VisualizeButton               matlab.ui.control.Button
    end

    properties (Access = private)
        file % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            constants = [app.BasicdoseCheckBox.Value, app.CVaRCheckBox.Value, app.UpperboundsCheckBox.Value, app.UniformityCheckBox_2.Value];
            objectives = [app.MinhealthyCheckBox.Value, app.MintargetCheckBox.Value, app.UniformityCheckBox.Value];
            layers = app.layersEditField.Value;
            voxelsField = app.voxelsEditField.Value;
            samplingType = app.SamplingTypeDropDown.Value;
            fileSelected = app.FileselectedTextArea.Value;
            sampleinterval = app.SampleintervalEditField.Value;
            clusters = app.numofclustersEditField.Value;
            
            switch samplingType
                case "Downsample"
                    [d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose, sampling_runtime] = integerdownsample(app.file, str2double(sampleinterval));
                case "K Clustering"
                    [d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose, sampling_runtime] = kmeansclustering(app.file, str2num(clusters), str2double(sampleinterval));
                case "Manham K Clustering"
                    [d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose, sampling_runtime] = kmeansclusteringmanham(app.file, str2num(clusters), str2double(sampleinterval));
                case "Fountain Clustering"
                    [d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose, sampling_runtime] = fountainclustering(app.file, str2double(sampleinterval));
            end
            
            %[a, b, c] = run_FMO(samplingType, voxelsField, layers, objectives, d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose);
            %app.OutputTextArea.Value = [a,newline,b,newline,"Sampling runtime = "+num2str(sampling_runtime)+" sec",newline,c];
            
            [a, b, c, d] = run_FMO(samplingType, voxelsField, layers, objectives, d_target, d_OAR, num_target_voxels, num_OAR_voxels, num_beamlets, target_dose);
            %import run_FMO matrix output txt file = d
            [avg_deviation, max_overage, voxels_underdosed] = metrics(app.file, d);
            app.OutputTextArea.Value = ["Average deviation = "+num2str(avg_deviation), newline, "Max overage = "+num2str(max_overage), newline, "Voxels underdosed = "+num2str(voxels_underdosed)];
        end

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            %update file selection text field with file path chosen
            %call uigetfile here
            [app.file, path] = uigetfile('*.mat');
            app.FileselectedTextArea.Value = strcat(path, app.file);
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            input = load(app.file);
            visualizeStructures(input.guiInput.voxelIndices);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SamplingTypeDropDownLabel = uilabel(app.UIFigure);
            app.SamplingTypeDropDownLabel.Text = 'Sampling Type';
            app.SamplingTypeDropDown = uidropdown(app.UIFigure);
            app.SamplingTypeDropDown.Items = {'Downsample', 'K Clustering', 'Manham K Clustering', 'Fountain Clustering'};
            app.SamplingTypeDropDown.Value = 'Downsample';
            app.UniformityCheckBox = uicheckbox(app.UIFigure);
            app.UniformityCheckBox.Text = 'Uniformity';
            app.MintargetCheckBox = uicheckbox(app.UIFigure);
            app.MintargetCheckBox.Text = 'Min. target';
            app.MinhealthyCheckBox = uicheckbox(app.UIFigure);
            app.MinhealthyCheckBox.Text = 'Min. healthy';
            app.FMOObjectivesLabel = uilabel(app.UIFigure);
            app.FMOObjectivesLabel.Text = 'FMO Objectives';
            app.UniformityCheckBox_2 = uicheckbox(app.UIFigure);
            app.UniformityCheckBox_2.Text = 'Uniformity';
            app.UpperboundsCheckBox = uicheckbox(app.UIFigure);
            app.UpperboundsCheckBox.Text = 'Upper bounds';
            app.CVaRCheckBox = uicheckbox(app.UIFigure);
            app.CVaRCheckBox.Text = 'CVaR';
            app.BasicdoseCheckBox = uicheckbox(app.UIFigure);
            app.BasicdoseCheckBox.Text = 'Basic dose';
            app.FMOConstraintsLabel = uilabel(app.UIFigure);
            app.FMOConstraintsLabel.Text = 'FMO Constraints';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.layersEditFieldLabel = uilabel(app.UIFigure);
            app.layersEditFieldLabel.Text = '# layers';
            app.layersEditField = uieditfield(app.UIFigure, 'text');
            app.voxelsEditFieldLabel = uilabel(app.UIFigure);
            app.voxelsEditFieldLabel.Text = '% voxels';
            app.voxelsEditField = uieditfield(app.UIFigure, 'text');
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.ChoosefileButton = uibutton(app.UIFigure, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.FileselectedTextAreaLabel = uilabel(app.UIFigure);
            app.FileselectedTextAreaLabel.Text = 'File selected';
            app.FileselectedTextArea = uitextarea(app.UIFigure);
            app.SampleintervalEditFieldLabel = uilabel(app.UIFigure);
            app.SampleintervalEditFieldLabel.Text = 'Sample interval';
            app.SampleintervalEditField = uieditfield(app.UIFigure, 'text');
            app.ofclustersEditFieldLabel = uilabel(app.UIFigure);
            app.ofclustersEditFieldLabel.Text = '# of clusters';
            app.numofclustersEditField = uieditfield(app.UIFigure, 'text');
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = samplingBenchmarkInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end